classdef AnalogInputRecorder_03311129 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        ai2CheckBox               matlab.ui.control.CheckBox
        ai1CheckBox               matlab.ui.control.CheckBox
        SelectChannelLabel        matlab.ui.control.Label
        ai0CheckBox               matlab.ui.control.CheckBox
        Switch                    matlab.ui.control.Switch
        RatescanssEditField       matlab.ui.control.NumericEditField
        RatescanssEditFieldLabel  matlab.ui.control.Label
        DeviceListDropDown        matlab.ui.control.DropDown
        DeviceListDropDownLabel   matlab.ui.control.Label
        PlotToolPanel             matlab.ui.container.Panel
        YAxisScalingButtonGroup   matlab.ui.container.ButtonGroup
        MaxEditField              matlab.ui.control.NumericEditField
        MaxEditFieldLabel         matlab.ui.control.Label
        MinEditField              matlab.ui.control.NumericEditField
        MinEditFieldLabel         matlab.ui.control.Label
        SetYLimitsButton          matlab.ui.control.RadioButton
        AutoacaleButton           matlab.ui.control.RadioButton
        ShowLegendCheckBox        matlab.ui.control.CheckBox
        RefreshButton             matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        timerObj     % タイマー
        daqObj       % DAQオブジェクト
        lineHandle   % プロット用 line オブジェクト（配列）
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                % デバイス一覧取得
                devList = daqlist("ni");  % NI製DAQデバイス前提
            
                if isempty(devList)
                    uialert(app.UIFigure, 'DAQデバイスが見つかりません', 'エラー');
                    return;
                end
            
                % Device List 更新
                app.DeviceListDropDown.Items = devList.DeviceID;
                app.DeviceListDropDown.Value = devList.DeviceID(1);
                app.MinEditField.Value = -10;
                app.MaxEditField.Value = 10;
                app.MinEditField.Enable = false;
                app.MaxEditField.Enable = false;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
                devList = daqlist("ni");

                if isempty(devList)
                    uialert(app.UIFigure, 'DAQデバイスが見つかりません', 'エラー');
                    app.DeviceListDropDown.Items = {};
                    return;
                end
            
                app.DeviceListDropDown.Items = devList.DeviceID;
                app.DeviceListDropDown.Value = devList.DeviceID(1);
        end

        % Value changed function: RatescanssEditField, Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;

            if strcmp(value, 'Start')
                % 測定開始時にUIをロック
                channels = {};
                if app.ai0CheckBox.Value; channels{end+1} = 'ai0'; end
                if app.ai1CheckBox.Value; channels{end+1} = 'ai1'; end
                if app.ai2CheckBox.Value; channels{end+1} = 'ai2'; end

        
                if isempty(channels)
                    uialert(app.UIFigure, '少なくとも1つのチャンネルを選択してください', 'エラー');
                    app.Switch.Value = 'Stop';
                    return;
                end
                % チャンネル選択されていればロック
                app.DeviceListDropDown.Enable = false;
                app.RefreshButton.Enable = false;
                app.RatescanssEditField.Enable = false;
                app.ai0CheckBox.Enable = false;
                app.ai1CheckBox.Enable = false;
                app.ai2CheckBox.Enable = false;
        
                devID = app.DeviceListDropDown.Value;
                rate = app.RatescanssEditField.Value;
        
                try
                    % DAQ作成
                    app.daqObj = daq("ni");
                    for i = 1:length(channels)
                        addinput(app.daqObj, devID, channels{i}, "Voltage");
                    end
                    app.daqObj.Rate = rate;
        
                    % プロット初期化
                    cla(app.UIAxes);
                    hold(app.UIAxes, 'on');
                    grid(app.UIAxes, 'on');
                    app.UIAxes.GridLineStyle = '--';
    
                    N = round(rate * 0.1);  % 0.1秒分のデータ長
                    numCh = numel(app.daqObj.Channels);
                    app.lineHandle = gobjects(1, numCh);
                    for i = 1:numCh
                        app.lineHandle(i) = plot(app.UIAxes, zeros(1, N), zeros(1, N), '-');
                    end
        
                    % タイマー作成
                    app.timerObj = timer( ...
                        'ExecutionMode', 'fixedSpacing', ...
                        'Period', 0.2, ... % 0.2秒ごと
                        'TimerFcn', @(~,~) updatePlot(app));
        
                    start(app.timerObj);
        
                catch ME
                    uialert(app.UIFigure, sprintf("DAQエラー: %s", ME.message), "エラー");
                    app.Switch.Value = 'Stop';
                end
        
            else
                 % 測定終了時にUIを再び有効化
                app.DeviceListDropDown.Enable = true;
                app.RefreshButton.Enable = true;
                app.RatescanssEditField.Enable = true;
                app.ai0CheckBox.Enable = true;
                app.ai1CheckBox.Enable = true;
                app.ai2CheckBox.Enable = true;
                % 測定停止処理
                if ~isempty(app.timerObj) && isvalid(app.timerObj)
                    stop(app.timerObj);
                    delete(app.timerObj);
                    app.timerObj = [];
                end
            
                if ~isempty(app.daqObj)
                    app.daqObj = [];
                end
            
                disp("測定を停止しました");
            end
            
        end

        % Callback function
        function updatePlot(app, event)
            try
                data = read(app.daqObj, seconds(0.1));
                t = seconds(data.Time - data.Time(1));
                y = data.Variables;
    
                % lineハンドルを更新
                for i = 1:size(y, 2)
                    app.lineHandle(i).XData = t;
                    app.lineHandle(i).YData = y(:, i);
                end       
               
        
                % 凡例
                if app.ShowLegendCheckBox.Value
                    lgd = legend(app.UIAxes, app.daqObj.Channels.ID, "Location", "northeast");
                    lgd.FontSize = 17;
                else
                    legend(app.UIAxes, 'off');
                end

                % Y軸スケール
                if app.AutoacaleButton.Value
                    ylim(app.UIAxes, 'auto');
                elseif app.SetYLimitsButton.Value
                    minVal = app.MinEditField.Value;
                    maxVal = app.MaxEditField.Value;
                    if ~isnan(minVal) && ~isnan(maxVal) && minVal < maxVal
                        ylim(app.UIAxes, [minVal, maxVal]);
                    end
                end
        
                % X軸：その0.1秒の範囲のみ
                xlim(app.UIAxes, [0, seconds(data.Time(end) - data.Time(1))]);
                
        
            catch ME
                disp("プロット更新エラー:");
                disp(getReport(ME));
                app.Switch.Value = 'Stop';
            end
        end

        % Selection changed function: YAxisScalingButtonGroup
        function YAxisScalingButtonGroupSelectionChanged(app, event)
            selected = app.YAxisScalingButtonGroup.SelectedObject;

            if selected == app.AutoacaleButton
                % Autoスケールを有効
                app.MinEditField.Enable = false;
                app.MaxEditField.Enable = false;
                ylim(app.UIAxes, 'auto');
        
            elseif selected == app.SetYLimitsButton
                % 手動設定を有効
                app.MinEditField.Enable = true;
                app.MaxEditField.Enable = true;
        
                % 入力されたMin/Maxに反映
                minVal = app.MinEditField.Value;
                maxVal = app.MaxEditField.Value;
                if ~isempty(minVal) && ~isempty(maxVal) && minVal < maxVal
                    ylim(app.UIAxes, [minVal, maxVal]);
                end
            end            
        end

        % Callback function
        function updateYAxisLimit(app, event)
            if app.SetYLimitsButton.Value
                minVal = app.MinEditField.Value;
                maxVal = app.MaxEditField.Value;
        
                % NaNチェック
                if isnan(minVal) || isnan(maxVal)
                    return;
                end
        
                % 最小・最大の正当性チェック
                if minVal < maxVal
                    ylim(app.UIAxes, [minVal, maxVal]);
                end
            end
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            updateYAxisLimit(app)
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            updateYAxisLimit(app)            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {592, 592};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {292, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.RefreshButton = uibutton(app.GridLayout2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PlotToolPanel = uipanel(app.GridLayout2);
            app.PlotToolPanel.Title = 'Plot Tool';
            app.ShowLegendCheckBox = uicheckbox(app.PlotToolPanel);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            app.YAxisScalingButtonGroup = uibuttongroup(app.PlotToolPanel);
            app.YAxisScalingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YAxisScalingButtonGroupSelectionChanged, true);
            app.YAxisScalingButtonGroup.Title = 'Y-Axis Scaling';
            app.AutoacaleButton = uiradiobutton(app.YAxisScalingButtonGroup);
            app.AutoacaleButton.Text = 'Autoacale';
            app.AutoacaleButton.Value = true;
            app.SetYLimitsButton = uiradiobutton(app.YAxisScalingButtonGroup);
            app.SetYLimitsButton.Text = 'Set Y-Limits';
            app.MinEditFieldLabel = uilabel(app.YAxisScalingButtonGroup);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.YAxisScalingButtonGroup, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.YAxisScalingButtonGroup);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.YAxisScalingButtonGroup, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.DeviceListDropDownLabel = uilabel(app.GridLayout2);
            app.DeviceListDropDownLabel.Text = 'Device List';
            app.DeviceListDropDown = uidropdown(app.GridLayout2);
            app.RatescanssEditFieldLabel = uilabel(app.GridLayout2);
            app.RatescanssEditFieldLabel.Text = 'Rate (scans/s)';
            app.RatescanssEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RatescanssEditField.Limits = [100 100000];
            app.RatescanssEditField.ValueDisplayFormat = '%.0f';
            app.RatescanssEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.RatescanssEditField.Value = 5000;
            app.Switch = uiswitch(app.GridLayout2, 'slider');
            app.Switch.Items = {'Stop', 'Start'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Stop';
            app.ai0CheckBox = uicheckbox(app.GridLayout2);
            app.ai0CheckBox.Text = 'ai0';
            app.SelectChannelLabel = uilabel(app.GridLayout2);
            app.SelectChannelLabel.Text = 'Select Channel';
            app.ai1CheckBox = uicheckbox(app.GridLayout2);
            app.ai1CheckBox.Text = 'ai1';
            app.ai2CheckBox = uicheckbox(app.GridLayout2);
            app.ai2CheckBox.Text = 'ai2';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalogInputRecorder_03311129

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end